classdef RagaPredictorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RagaIsEditField           matlab.ui.control.EditField
        RagaIsEditFieldLabel      matlab.ui.control.Label
        PredictButton             matlab.ui.control.Button
        SpectroButton             matlab.ui.control.Button
        PlayAudioButton           matlab.ui.control.Button
        LoadedFileEditField       matlab.ui.control.EditField
        LoadedFileEditFieldLabel  matlab.ui.control.Label
        OpenaudioFileButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        y
        fs
        path
        filename
        filesave
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenaudioFileButton
        function OpenaudioFileButtonPushed(app, event)
           [app.filename,app.path] = uigetfile();
           app.LoadedFileEditField.Value = fullfile(app.path,app.filename)
           
        end

        % Value changed function: LoadedFileEditField
        function LoadedFileEditFieldValueChanged(app, event)
            value = app.LoadedFileEditField.Value;
            
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
           [app.y,app.fs] = audioread(app.LoadedFileEditField.Value);
           sound(app.y,app.fs);
        end

        % Button pushed function: SpectroButton
        function SpectroButtonPushed(app, event)
            melSpectrogram(app.y,app.fs);
            colorbar ('off');
            axis off;
            f=gcf;
            [fname type] = fileparts(app.filename)
            app.filesave = fullfile(pwd,append(type,'.jpg'))
            saveas(f,app.filesave);
            img = imread(app.filesave,"jpg");
            crop_im = imcrop(img,[115 50 675 535]);
            imwrite(crop_im,app.filesave);
            %imshow(crop_im)
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            X = imread(app.filesave);
            Y = imresize(X,[400 400]);
            load("trainednet.mat");
            [predict, scores] = classify(trainednet,Y)
            app.RagaIsEditField.Value= append(string(predict),' ', num2str(scores)) 
        end

        % Callback function
        function PredictedRagaEditFieldValueChanged(app, event)
            value =   app.PredictedRagaEditFieldValue
            
        end

        % Value changed function: RagaIsEditField
        function RagaIsEditFieldValueChanged(app, event)
            value = app.RagaIsEditField.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenaudioFileButton = uibutton(app.UIFigure, 'push');
            app.OpenaudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenaudioFileButtonPushed, true);
            app.OpenaudioFileButton.Text = 'Open audio File';
            app.LoadedFileEditFieldLabel = uilabel(app.UIFigure);
            app.LoadedFileEditFieldLabel.Text = 'Loaded File';
            app.LoadedFileEditField = uieditfield(app.UIFigure, 'text');
            app.LoadedFileEditField.ValueChangedFcn = createCallbackFcn(app, @LoadedFileEditFieldValueChanged, true);
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.SpectroButton = uibutton(app.UIFigure, 'push');
            app.SpectroButton.ButtonPushedFcn = createCallbackFcn(app, @SpectroButtonPushed, true);
            app.SpectroButton.Text = 'Spectro';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.RagaIsEditFieldLabel = uilabel(app.UIFigure);
            app.RagaIsEditFieldLabel.Text = 'RagaIs';
            app.RagaIsEditField = uieditfield(app.UIFigure, 'text');
            app.RagaIsEditField.ValueChangedFcn = createCallbackFcn(app, @RagaIsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RagaPredictorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end