classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        read_writeButton        matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        TextAreaLabel           matlab.ui.control.Label
        EditField               matlab.ui.control.EditField
        EditFieldLabel          matlab.ui.control.Label
        DirectoryButton         matlab.ui.control.Button
        ImportDataButton        matlab.ui.control.Button
        FilenameEditField       matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        ImportButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename,path] = uigetfile();
            app.FilenameEditField.Value=fullfile(path,filename);
            figure(app.UIFigure);
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
           [file,path,indx] = uigetfile()
           filename = [path file]
           A = importdata(filename)
           [y,fs] = audioread(filename)
           sound(y,fs)
        end

        % Button pushed function: DirectoryButton
        function DirectoryButtonPushed(app, event)
            selgerdir = uigetdir()
        end

        % Button pushed function: read_writeButton
        function read_writeButtonPushed(app, event)
            B = app.EditField.Value
            %app.EditField.Value = 'HELLO'

            C = app.TextArea.Value
            D = app.TextArea.Value{1}
            E = app.TextArea.Value{2}
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
         
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'ImportData';
            app.DirectoryButton = uibutton(app.UIFigure, 'push');
            app.DirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DirectoryButtonPushed, true);
            app.DirectoryButton.Text = 'Directory';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.read_writeButton = uibutton(app.UIFigure, 'push');
            app.read_writeButton.ButtonPushedFcn = createCallbackFcn(app, @read_writeButtonPushed, true);
            app.read_writeButton.Text = 'read_write';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end